classdef tugas2PCDmain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImageMotionBlur                 matlab.ui.control.Image
        ImageRestoredMotion             matlab.ui.control.Image
        NoisevarInput                   matlab.ui.control.NumericEditField
        NoisevarLabel                   matlab.ui.control.Label
        AngleInput                      matlab.ui.control.NumericEditField
        AngleLabel                      matlab.ui.control.Label
        LengthInput                     matlab.ui.control.NumericEditField
        LengthLabel                     matlab.ui.control.Label
        D1HighCutoffInput               matlab.ui.control.NumericEditField
        D1HighCutoffLabel               matlab.ui.control.Label
        D0LowCutoffInput                matlab.ui.control.NumericEditField
        D0LowCutoffLabel                matlab.ui.control.Label
        WidthInput                      matlab.ui.control.NumericEditField
        WidthLabel                      matlab.ui.control.Label
        D0DistanceInput                 matlab.ui.control.NumericEditField
        D0DistanceLabel                 matlab.ui.control.Label
        DropdownPeriodicNoiseType       matlab.ui.control.DropDown
        FilterTypePeriodicNoiseLabel    matlab.ui.control.Label
        ImageWithNoiseLabel             matlab.ui.control.Label
        RestoredImageLabel              matlab.ui.control.Label
        NoiseImage                      matlab.ui.control.Image
        OutputRestoredNoise             matlab.ui.control.Image
        VarianceInput                   matlab.ui.control.NumericEditField
        VarianceLabel                   matlab.ui.control.Label
        DensityInput                    matlab.ui.control.NumericEditField
        DensityLabel                    matlab.ui.control.Label
        MeanInput                       matlab.ui.control.NumericEditField
        MeanLabel                       matlab.ui.control.Label
        nWindowInput                    matlab.ui.control.NumericEditField
        nWindowLabel                    matlab.ui.control.Label
        DropdownNoiseType_2             matlab.ui.control.DropDown
        FilterTypeLabel                 matlab.ui.control.Label
        DropdownNoiseType               matlab.ui.control.DropDown
        NoiseTypeLabel                  matlab.ui.control.Label
        GammaHInput                     matlab.ui.control.NumericEditField
        GammaHLabel                     matlab.ui.control.Label
        GammaLInput                     matlab.ui.control.NumericEditField
        GammaLLabel                     matlab.ui.control.Label
        CutOffImageBrighteningInput     matlab.ui.control.NumericEditField
        CutoffImageBrighteningLabel     matlab.ui.control.Label
        CutOffFrequencyInput            matlab.ui.control.NumericEditField
        CutoffFrequencyLabel            matlab.ui.control.Label
        DropdownSmoothingTypeEdgeDetectionFrequency  matlab.ui.control.DropDown
        DropDownEdgeDetectionDomain     matlab.ui.control.DropDown
        MeshGraphSmoothingFrequencyLabel  matlab.ui.control.Label
        MeshArraySmoothingFrequencyLabel  matlab.ui.control.Label
        GambarOutputSmoothingFrequencyLabel  matlab.ui.control.Label
        MeshGraphSmoothingSpacial       matlab.ui.control.Image
        MeshArraySmoothingSpacial       matlab.ui.control.Image
        OutputImageSmoothingSpacial     matlab.ui.control.Image
        OrderInput                      matlab.ui.control.NumericEditField
        OrderLabel                      matlab.ui.control.Label
        CutOffInput                     matlab.ui.control.NumericEditField
        CutoffLabel                     matlab.ui.control.Label
        DropdownSmoothingTypeFrequency  matlab.ui.control.DropDown
        GambarOutputSmoothingSpatialLabel  matlab.ui.control.Label
        GambarOutputSmoothingSpatial    matlab.ui.control.Image
        SigmaInput                      matlab.ui.control.NumericEditField
        SigmaLabel                      matlab.ui.control.Label
        nMaskInput                      matlab.ui.control.NumericEditField
        nMaskLabel                      matlab.ui.control.Label
        SmoothingTypeDropdown           matlab.ui.control.DropDown
        SmoothingTypeLabel              matlab.ui.control.Label
        SmoothingDomainLabel            matlab.ui.control.Label
        SmoothingDomainDropdown         matlab.ui.control.DropDown
        EditFieldMask                   matlab.ui.control.EditField
        ProcessImageButton              matlab.ui.control.Button
        NamaFile_2                      matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        NamaFile                        matlab.ui.control.Label
        OutputLibraryLabel              matlab.ui.control.Label
        GambarOutputLabel               matlab.ui.control.Label
        OutputLibrary                   matlab.ui.control.Image
        FourierDisplayInputLabel        matlab.ui.control.Label
        GambarInputLabel                matlab.ui.control.Label
        OutputScratch                   matlab.ui.control.Image
        FourierInput                    matlab.ui.control.Image
        InputImage                      matlab.ui.control.Image
        PilihGambarButton               matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.tif'});
            if isequal(file, 0)
                return;
            end
            
            fullPath = fullfile(path, file);
            app.NamaFile.Text = file;             
            app.NamaFile.UserData = fullPath;       
            
            app.InputImage.ImageSource = fullPath;

            img = imread(fullPath);
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
            
            % Compute and display FFT
            F = fft2(double(img));
            F_shifted = fftshift(F);
            magnitude = log(1 + abs(F_shifted));
            magnitude = mat2gray(magnitude);
            
            timestamp = datestr(now, 'yyyymmdd_HHMMSS_FFF');
            temp_file = fullfile(tempdir, ['fourier_display_' timestamp '.png']);
            imwrite(magnitude, temp_file);
            
            app.FourierInput.ImageSource = temp_file;
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            % Button pushed function: ProcessImageButton
            imagePath = app.NamaFile.UserData;
            if isempty(imagePath)
                uialert(app.UIFigure, 'Silakan pilih gambar terlebih dahulu.', 'Peringatan');
                return;
            end
            
            operation = app.DropDown.Value;
            addpath('src');
            
            % Generate timestamp untuk nama file unik
            timestamp = datestr(now, 'yyyymmdd_HHMMSS_FFF');
            
            switch operation
                case 'Konvolusi'
                   maskText = strtrim(string(app.EditFieldMask.Value)); 
                    nums = str2double(strsplit(maskText)); 
                    len = numel(nums);
                    n = sqrt(len);
                    
                    if mod(n,1) ~= 0
                        uialert(app.UIFigure, 'Jumlah angka harus membentuk matriks persegi (3x3, 5x5, dst).', 'Error');
                        return;
                    end
                    
                    mask = reshape(nums, [n n])';  % pastikan baris per baris
                    
                    % Normalisasi mask jika bukan deteksi tepi
                    if sum(mask(:)) ~= 0
                        mask = mask / sum(mask(:));
                    end
                    
                    result_manual = convolusion(imagePath, mask);
                    
                    % Konvolusi built-in (conv2)
                    img_builtin = im2double(imread(imagePath));
                    if size(img_builtin,3) == 3
                        r = conv2(img_builtin(:,:,1), mask, 'same');
                        g = conv2(img_builtin(:,:,2), mask, 'same');
                        b = conv2(img_builtin(:,:,3), mask, 'same');
                        result_builtin = cat(3, r, g, b);
                    else
                        result_builtin = conv2(img_builtin, mask, 'same');
                    end
                    
                    result_builtin = max(0, min(1, result_builtin));
                    result_builtin = im2uint8(result_builtin);
                    
                    timestamp = datestr(now, 'yyyymmdd_HHMMSSFFF');
                    temp_manual = fullfile(tempdir, ['result_manual_' timestamp '.png']);
                    temp_builtin = fullfile(tempdir, ['result_builtin_' timestamp '.png']);
                    
                    imwrite(result_manual, temp_manual);
                    imwrite(result_builtin, temp_builtin);
                    
                    app.OutputScratch.ImageSource = temp_manual;
                    app.OutputLibrary.ImageSource = temp_builtin;

                    
                case 'Image Smoothing'
                    domain = app.SmoothingDomainDropdown.Value;
                    smoothType = app.DropdownSmoothingTypeFrequency.Value;
                    
                    if strcmp(domain, 'Spasial')
                        nMask = round(app.nMaskInput.Value);
                        sigma = app.SigmaInput.Value;
                        
                        if nMask < 3 || mod(nMask, 2) == 0
                            uialert(app.UIFigure, 'n Mask harus ganjil dan >= 3 (contoh: 3, 5, 7, ...)', 'Error');
                            return;
                        end
                        
                        if strcmp(smoothType, 'Gaussian') && sigma <= 0
                            uialert(app.UIFigure, 'Sigma harus > 0', 'Error');
                            return;
                        end
                        
                        result_smoothing = imageSmoothingSpatial(imagePath, nMask, sigma, smoothType);
                        
                        temp_smoothing = fullfile(tempdir, ['result_smoothing_spatial_' timestamp '.png']);
                        imwrite(result_smoothing, temp_smoothing);
                        
                        app.GambarOutputSmoothingSpatial.ImageSource = temp_smoothing;
                        
                    else
                        filterType = smoothType;
                        cutOff = app.CutOffInput.Value;
                        order = app.OrderInput.Value;
                        
                        if cutOff <= 0
                            uialert(app.UIFigure, 'Cutoff frequency harus > 0', 'Error');
                            return;
                        end
                        
                        if strcmp(filterType, 'BLPF') && order <= 0
                            uialert(app.UIFigure, 'Order harus > 0 untuk Butterworth filter', 'Error');
                            return;
                        end
                        
                        [resultImage, filterMask, filterGraph] = ...
                            imageSmoothingFrequency(imagePath, cutOff, order, filterType);
                        
                        temp_result = fullfile(tempdir, ['result_freq_' timestamp '.png']);
                        temp_mask = fullfile(tempdir, ['filter_mask_' timestamp '.png']);
                        temp_graph = fullfile(tempdir, ['filter_graph_' timestamp '.png']);
                        
                        imwrite(resultImage, temp_result);
                        imwrite(filterMask, temp_mask);
                        imwrite(filterGraph, temp_graph);
                        
                        app.OutputImageSmoothingSpacial.ImageSource = temp_result;
                        app.MeshArraySmoothingSpacial.ImageSource = temp_mask;
                        app.MeshGraphSmoothingSpacial.ImageSource = temp_graph;
                    end
                    
                case 'Edge Detection'
                    % Edge Detection menggunakan High-Pass Filter
                    filterType = app.DropdownSmoothingTypeEdgeDetectionFrequency.Value;
                    cutOff = app.CutOffFrequencyInput.Value;
                    order = 2; % Fixed order
                    
                    % Validasi
                    if cutOff <= 0
                        uialert(app.UIFigure, 'Cutoff frequency harus > 0', 'Error');
                        return;
                    end
                    
                    % Panggil fungsi edge detection
                    [resultImage, filterMask, filterGraph] = ...
                        edgeDetection(imagePath, cutOff, order, filterType);
                    
                    % Simpan dengan timestamp
                    temp_result = fullfile(tempdir, ['edge_result_' timestamp '.png']);
                    temp_mask = fullfile(tempdir, ['edge_mask_' timestamp '.png']);
                    temp_graph = fullfile(tempdir, ['edge_graph_' timestamp '.png']);
                    
                    imwrite(resultImage, temp_result);
                    imwrite(filterMask, temp_mask);
                    imwrite(filterGraph, temp_graph);
                    
                    app.OutputImageSmoothingSpacial.ImageSource = temp_result;
                    app.MeshArraySmoothingSpacial.ImageSource = temp_mask;
                    app.MeshGraphSmoothingSpacial.ImageSource = temp_graph;
                    
                case 'Image Brightening'
                    % Image Brightening menggunakan Homomorphic Filtering
                    cutOff = app.CutOffImageBrighteningInput.Value;
                    gammaL = app.GammaLInput.Value;
                    gammaH = app.GammaHInput.Value;
                    
                    % Validasi
                    if cutOff <= 0
                        uialert(app.UIFigure, 'Cutoff frequency harus > 0', 'Error');
                        return;
                    end
                    
                    % Panggil fungsi homomorphic filtering
                    [resultImage, filterMask, filterGraph] = ...
                        homomorphicFiltering(imagePath, cutOff, gammaL, gammaH);
                    
                    % Simpan dengan timestamp
                    temp_result = fullfile(tempdir, ['brightening_result_' timestamp '.png']);
                    temp_mask = fullfile(tempdir, ['brightening_mask_' timestamp '.png']);
                    temp_graph = fullfile(tempdir, ['brightening_graph_' timestamp '.png']);
                    
                    imwrite(resultImage, temp_result);
                    imwrite(filterMask, temp_mask);
                    imwrite(filterGraph, temp_graph);
                    
                    app.OutputImageSmoothingSpacial.ImageSource = temp_result;
                    app.MeshArraySmoothingSpacial.ImageSource = temp_mask;
                    app.MeshGraphSmoothingSpacial.ImageSource = temp_graph;
                    
                case 'Noise Filtering'
                    % Noise Filtering (Restoration)
                    noiseType = app.DropdownNoiseType.Value;
                    filterType = app.DropdownNoiseType_2.Value;
                    nWindow = round(app.nWindowInput.Value);
                    
                    % Validasi window size
                    if nWindow < 3 || mod(nWindow, 2) == 0
                        uialert(app.UIFigure, 'Window size harus ganjil dan >= 3', 'Error');
                        return;
                    end
                    
                    % Get noise parameters
                    if strcmp(noiseType, 'Salt and Pepper')
                        density = app.DensityInput.Value;
                        meanNoise = 0;
                        variance = 0;
                        
                        if density <= 0 || density >= 1
                            uialert(app.UIFigure, 'Density harus antara 0 dan 1 (typical: 0.05)', 'Error');
                            return;
                        end
                        
                        % Convert noise type name
                        noiseTypeParam = 'salt_pepper';
                    else  % Gaussian
                        density = 0;
                        meanNoise = app.MeanInput.Value;
                        variance = app.VarianceInput.Value;
                        
                        if variance <= 0
                            uialert(app.UIFigure, 'Variance harus > 0 (typical: 0.01)', 'Error');
                            return;
                        end
                        
                        noiseTypeParam = 'gaussian';
                    end
                    
                    % Convert filter type name to lowercase
                    filterTypeParam = lower(strrep(filterType, ' ', '_'));
                    
                    % Panggil fungsi noise restoration
                    [restoredImage, noisyImage] = noiseRestoration(imagePath, ...
                        noiseTypeParam, filterTypeParam, nWindow, meanNoise, variance, density);
                    
                    % Simpan dengan timestamp
                    temp_noisy = fullfile(tempdir, ['noisy_image_' timestamp '.png']);
                    temp_restored = fullfile(tempdir, ['restored_image_' timestamp '.png']);
                    
                    imwrite(noisyImage, temp_noisy);
                    imwrite(restoredImage, temp_restored);
                    
                    app.NoiseImage.ImageSource = temp_noisy;
                    app.OutputRestoredNoise.ImageSource = temp_restored;
                    
                case 'Periodic Noise'
                    % Periodic Noise Removal
                    filterType = app.DropdownPeriodicNoiseType.Value;
                    
                    % Convert filter type
                    if strcmp(filterType, 'Band Reject')
                        filterTypeParam = 'bandreject';
                        distance = app.D0DistanceInput.Value;
                        width = app.WidthInput.Value;
                        lowCutoff = 0;
                        highCutoff = 0;
                        
                        if distance <= 0
                            uialert(app.UIFigure, 'Distance (D0) harus > 0', 'Error');
                            return;
                        end
                        
                        if width <= 0
                            uialert(app.UIFigure, 'Width harus > 0', 'Error');
                            return;
                        end
                        
                    elseif strcmp(filterType, 'Band Pass')
                        filterTypeParam = 'bandpass';
                        lowCutoff = app.D0LowCutoffInput.Value;
                        highCutoff = app.D1HighCutoffInput.Value;
                        distance = 0;
                        width = 0;
                        
                        if lowCutoff <= 0 || highCutoff <= 0
                            uialert(app.UIFigure, 'Cutoff values harus > 0', 'Error');
                            return;
                        end
                        
                        if highCutoff <= lowCutoff
                            uialert(app.UIFigure, 'High Cutoff harus > Low Cutoff', 'Error');
                            return;
                        end
                        
                    else  % Notch
                        filterTypeParam = 'notch';
                        lowCutoff = app.D0LowCutoffInput.Value;
                        highCutoff = 0;
                        distance = 0;
                        width = 0;
                        
                        if lowCutoff <= 0
                            uialert(app.UIFigure, 'Notch distance harus > 0', 'Error');
                            return;
                        end
                    end
                    
                    % Panggil fungsi periodic noise
                    [resultImage, filterMask, filterGraph] = periodicNoise(imagePath, ...
                        filterTypeParam, lowCutoff, highCutoff, distance, width);
                    
                    % Simpan dengan timestamp
                    temp_result = fullfile(tempdir, ['periodic_result_' timestamp '.png']);
                    temp_mask = fullfile(tempdir, ['periodic_mask_' timestamp '.png']);
                    temp_graph = fullfile(tempdir, ['periodic_graph_' timestamp '.png']);
                    
                    imwrite(resultImage, temp_result);
                    imwrite(filterMask, temp_mask);
                    imwrite(filterGraph, temp_graph);
                    
                    app.OutputImageSmoothingSpacial.ImageSource = temp_result;
                    app.MeshArraySmoothingSpacial.ImageSource = temp_mask;
                    app.MeshGraphSmoothingSpacial.ImageSource = temp_graph;
                    
                case 'Motion Blur'
                    % Motion Blur Degradation and Restoration
                    length = app.LengthInput.Value;
                    angle = app.AngleInput.Value;
                    noiseVar = app.NoisevarInput.Value;
                    
                    % Validasi
                    if length <= 0
                        uialert(app.UIFigure, 'Length harus > 0', 'Error');
                        return;
                    end
                    
                    if noiseVar < 0
                        uialert(app.UIFigure, 'Noise variance harus >= 0', 'Error');
                        return;
                    end
                    
                    % Panggil fungsi motion blur
                    [restoredImage, blurredImage] = motionBlur(imagePath, length, angle, noiseVar);
                    
                    % Simpan dengan timestamp
                    temp_blurred = fullfile(tempdir, ['blurred_' timestamp '.png']);
                    temp_restored = fullfile(tempdir, ['restored_' timestamp '.png']);
                    
                    imwrite(blurredImage, temp_blurred);
                    imwrite(restoredImage, temp_restored);
                    
                    app.ImageMotionBlur.ImageSource = temp_blurred;
                    app.ImageRestoredMotion.ImageSource = temp_restored;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
        
            % Reset SEMUA visibility controls
            app.EditFieldMask.Visible = 'off';
            app.NamaFile_2.Visible = 'off';
            app.SmoothingDomainDropdown.Visible = 'off';
            app.SmoothingDomainLabel.Visible = 'off';
            app.SmoothingTypeDropdown.Visible = 'off';
            app.SmoothingTypeLabel.Visible = 'off';
            app.nMaskLabel.Visible = 'off';
            app.nMaskInput.Visible = 'off';
            app.SigmaLabel.Visible = 'off';
            app.SigmaInput.Visible = 'off';
            app.DropdownSmoothingTypeFrequency.Visible = 'off';
            app.CutoffLabel.Visible = 'off';
            app.CutOffInput.Visible = 'off';
            app.OrderLabel.Visible = 'off';
            app.OrderInput.Visible = 'off';
            
            % Reset Edge Detection
            app.DropDownEdgeDetectionDomain.Visible = 'off';
            app.DropdownSmoothingTypeEdgeDetectionFrequency.Visible = 'off';
            app.CutoffFrequencyLabel.Visible = 'off';
            app.CutOffFrequencyInput.Visible = 'off';
            
            % Reset Image Brightening
            app.CutoffImageBrighteningLabel.Visible = 'off';
            app.CutOffImageBrighteningInput.Visible = 'off';
            app.GammaLLabel.Visible = 'off';
            app.GammaLInput.Visible = 'off';
            app.GammaHLabel.Visible = 'off';
            app.GammaHInput.Visible = 'off';
            
            % Reset Noise Filtering
            app.NoiseTypeLabel.Visible = 'off';
            app.DropdownNoiseType.Visible = 'off';
            app.FilterTypeLabel.Visible = 'off';
            app.DropdownNoiseType_2.Visible = 'off';
            app.nWindowLabel.Visible = 'off';
            app.nWindowInput.Visible = 'off';
            app.MeanLabel.Visible = 'off';
            app.MeanInput.Visible = 'off';
            app.DensityLabel.Visible = 'off';
            app.DensityInput.Visible = 'off';
            app.VarianceLabel.Visible = 'off';
            app.VarianceInput.Visible = 'off';
            
            % Reset Periodic Noise
            app.FilterTypePeriodicNoiseLabel.Visible = 'off';
            app.DropdownPeriodicNoiseType.Visible = 'off';
            app.D0DistanceLabel.Visible = 'off';
            app.D0DistanceInput.Visible = 'off';
            app.WidthLabel.Visible = 'off';
            app.WidthInput.Visible = 'off';
            app.D0LowCutoffLabel.Visible = 'off';
            app.D0LowCutoffInput.Visible = 'off';
            app.D1HighCutoffLabel.Visible = 'off';
            app.D1HighCutoffInput.Visible = 'off';
            
            % Reset Motion Blur
            app.LengthLabel.Visible = 'off';
            app.LengthInput.Visible = 'off';
            app.AngleLabel.Visible = 'off';
            app.AngleInput.Visible = 'off';
            app.NoisevarLabel.Visible = 'off';
            app.NoisevarInput.Visible = 'off';
            
            % Reset ALL outputs
            app.OutputScratch.Visible = 'off';
            app.OutputLibrary.Visible = 'off';
            app.GambarOutputLabel.Visible = 'off';
            app.OutputLibraryLabel.Visible = 'off';
            app.GambarOutputSmoothingSpatial.Visible = 'off';
            app.GambarOutputSmoothingSpatialLabel.Visible = 'off';
            app.OutputImageSmoothingSpacial.Visible = 'off';
            app.MeshArraySmoothingSpacial.Visible = 'off';
            app.MeshGraphSmoothingSpacial.Visible = 'off';
            app.GambarOutputSmoothingFrequencyLabel.Visible = 'off';
            app.MeshArraySmoothingFrequencyLabel.Visible = 'off';
            app.MeshGraphSmoothingFrequencyLabel.Visible = 'off';
            app.OutputRestoredNoise.Visible = 'off';
            app.NoiseImage.Visible = 'off';
            app.RestoredImageLabel.Visible = 'off';
            app.ImageWithNoiseLabel.Visible = 'off';
            app.ImageMotionBlur.Visible = 'off';
            app.ImageRestoredMotion.Visible = 'off';
            
            switch value
                case 'Konvolusi'
                    % Show mask input
                    app.EditFieldMask.Visible = 'on';
                    app.NamaFile_2.Visible = 'on';
                    app.NamaFile_2.Text = 'Input Mask';
                    
                    % Show dual output
                    app.OutputScratch.Visible = 'on';
                    app.OutputLibrary.Visible = 'on';
                    app.GambarOutputLabel.Visible = 'on';
                    app.GambarOutputLabel.Text = 'Output Scratch';
                    app.OutputLibraryLabel.Visible = 'on';
                    app.OutputLibraryLabel.Text = 'Output Library';
                    
                case 'Image Smoothing'
                    % Show smoothing domain dropdown
                    app.SmoothingDomainDropdown.Visible = 'on';
                    app.SmoothingDomainLabel.Visible = 'on';
                    app.SmoothingDomainLabel.Text = 'Smoothing Domain';
                    
                    domain = app.SmoothingDomainDropdown.Value;
                    app.DropdownSmoothingTypeFrequency.Visible = 'on';
                    app.SmoothingTypeLabel.Visible = 'on';
                    app.SmoothingTypeLabel.Text = 'Smoothing Type';  % Reset text
                    
                    if strcmp(domain, 'Spasial')
                        app.DropdownSmoothingTypeFrequency.Items = {'Mean', 'Gaussian'};
                        if ~ismember(app.DropdownSmoothingTypeFrequency.Value, {'Mean', 'Gaussian'})
                            app.DropdownSmoothingTypeFrequency.Value = 'Mean';
                        end
                        
                        app.nMaskLabel.Visible = 'on';
                        app.nMaskInput.Visible = 'on';
                        
                        % PERBAIKAN: Cek tipe smoothing untuk show/hide sigma
                        smoothType = app.DropdownSmoothingTypeFrequency.Value;
                        if strcmp(smoothType, 'Gaussian')
                            app.SigmaLabel.Visible = 'on';
                            app.SigmaInput.Visible = 'on';
                        else
                            app.SigmaLabel.Visible = 'off';
                            app.SigmaInput.Visible = 'off';
                        end
                        
                        app.GambarOutputSmoothingSpatial.Visible = 'on';
                        app.GambarOutputSmoothingSpatialLabel.Visible = 'on';
                        app.GambarOutputSmoothingSpatialLabel.Text = 'Gambar Output';
                        
                    else  % Frekuensi
                        app.DropdownSmoothingTypeFrequency.Items = {'ILPF', 'GLPF', 'BLPF'};
                        if ~ismember(app.DropdownSmoothingTypeFrequency.Value, {'ILPF', 'GLPF', 'BLPF'})
                            app.DropdownSmoothingTypeFrequency.Value = 'ILPF';
                        end
                        
                        app.CutoffLabel.Visible = 'on';
                        app.CutOffInput.Visible = 'on';
                        app.OrderLabel.Visible = 'on';
                        app.OrderInput.Visible = 'on';
                        
                        % Show 3 outputs dengan label
                        app.OutputImageSmoothingSpacial.Visible = 'on';
                        app.MeshArraySmoothingSpacial.Visible = 'on';
                        app.MeshGraphSmoothingSpacial.Visible = 'on';
                        app.GambarOutputSmoothingFrequencyLabel.Visible = 'on';
                        app.GambarOutputSmoothingFrequencyLabel.Text = 'Gambar Output';
                        app.MeshArraySmoothingFrequencyLabel.Visible = 'on';
                        app.MeshArraySmoothingFrequencyLabel.Text = 'Filter Mask';
                        app.MeshGraphSmoothingFrequencyLabel.Visible = 'on';
                        app.MeshGraphSmoothingFrequencyLabel.Text = 'Mesh Graph';
                    end
                    
                case 'Edge Detection'
                    % Show edge detection controls
                    app.DropDownEdgeDetectionDomain.Visible = 'on';
                    app.SmoothingDomainLabel.Visible = 'on';
                    app.SmoothingDomainLabel.Text = 'Domain';
                    
                    app.DropdownSmoothingTypeEdgeDetectionFrequency.Visible = 'on';
                    app.SmoothingTypeLabel.Visible = 'on';
                    app.SmoothingTypeLabel.Text = 'Filter Type';
                    
                    app.CutoffFrequencyLabel.Visible = 'on';
                    app.CutOffFrequencyInput.Visible = 'on';
                    
                    % Show 3 outputs dengan label
                    app.OutputImageSmoothingSpacial.Visible = 'on';
                    app.MeshArraySmoothingSpacial.Visible = 'on';
                    app.MeshGraphSmoothingSpacial.Visible = 'on';
                    app.GambarOutputSmoothingFrequencyLabel.Visible = 'on';
                    app.GambarOutputSmoothingFrequencyLabel.Text = 'Edge Output';
                    app.MeshArraySmoothingFrequencyLabel.Visible = 'on';
                    app.MeshArraySmoothingFrequencyLabel.Text = 'Filter Mask';
                    app.MeshGraphSmoothingFrequencyLabel.Visible = 'on';
                    app.MeshGraphSmoothingFrequencyLabel.Text = 'Mesh Graph';
                    
                case 'Image Brightening'
                    % Show image brightening controls
                    app.CutoffImageBrighteningLabel.Visible = 'on';
                    app.CutOffImageBrighteningInput.Visible = 'on';
                    app.GammaLLabel.Visible = 'on';
                    app.GammaLInput.Visible = 'on';
                    app.GammaHLabel.Visible = 'on';
                    app.GammaHInput.Visible = 'on';
                    
                    % Show 3 outputs dengan label
                    app.OutputImageSmoothingSpacial.Visible = 'on';
                    app.MeshArraySmoothingSpacial.Visible = 'on';
                    app.MeshGraphSmoothingSpacial.Visible = 'on';
                    app.GambarOutputSmoothingFrequencyLabel.Visible = 'on';
                    app.GambarOutputSmoothingFrequencyLabel.Text = 'Brightened';
                    app.MeshArraySmoothingFrequencyLabel.Visible = 'on';
                    app.MeshArraySmoothingFrequencyLabel.Text = 'Filter Mask';
                    app.MeshGraphSmoothingFrequencyLabel.Visible = 'on';
                    app.MeshGraphSmoothingFrequencyLabel.Text = 'Mesh Graph';
                    
                case 'Noise Filtering'
                    % Show noise filtering controls
                    app.NoiseTypeLabel.Visible = 'on';
                    app.DropdownNoiseType.Visible = 'on';
                    app.FilterTypeLabel.Visible = 'on';
                    app.DropdownNoiseType_2.Visible = 'on';
                    app.nWindowLabel.Visible = 'on';
                    app.nWindowInput.Visible = 'on';
                    
                    % Show appropriate noise parameters based on type
                    noiseType = app.DropdownNoiseType.Value;
                    if strcmp(noiseType, 'Salt and Pepper')
                        app.DensityLabel.Visible = 'on';
                        app.DensityInput.Visible = 'on';
                    else  % Gaussian
                        app.MeanLabel.Visible = 'on';
                        app.MeanInput.Visible = 'on';
                        app.VarianceLabel.Visible = 'on';
                        app.VarianceInput.Visible = 'on';
                    end
                    
                    app.OutputRestoredNoise.Visible = 'on';
                    app.NoiseImage.Visible = 'on';
                    app.RestoredImageLabel.Visible = 'on';
                    app.RestoredImageLabel.Text = 'Restored Image';
                    app.ImageWithNoiseLabel.Visible = 'on';
                    app.ImageWithNoiseLabel.Text = 'Image With Noise';
                    
                case 'Periodic Noise'
                    % Show periodic noise controls
                    app.FilterTypePeriodicNoiseLabel.Visible = 'on';
                    app.DropdownPeriodicNoiseType.Visible = 'on';
                    
                    filterType = app.DropdownPeriodicNoiseType.Value;
                    if strcmp(filterType, 'Band Reject')
                        app.D0DistanceLabel.Visible = 'on';
                        app.D0DistanceInput.Visible = 'on';
                        app.WidthLabel.Visible = 'on';
                        app.WidthInput.Visible = 'on';
                    elseif strcmp(filterType, 'Band Pass')
                        app.D0LowCutoffLabel.Visible = 'on';
                        app.D0LowCutoffInput.Visible = 'on';
                        app.D1HighCutoffLabel.Visible = 'on';
                        app.D1HighCutoffInput.Visible = 'on';
                    else  % Notch
                        app.D0LowCutoffLabel.Visible = 'on';
                        app.D0LowCutoffInput.Visible = 'on';
                    end
                    
                    % Show 3 outputs dengan label
                    app.OutputImageSmoothingSpacial.Visible = 'on';
                    app.MeshArraySmoothingSpacial.Visible = 'on';
                    app.MeshGraphSmoothingSpacial.Visible = 'on';
                    app.GambarOutputSmoothingFrequencyLabel.Visible = 'on';
                    app.GambarOutputSmoothingFrequencyLabel.Text = 'Filtered Output';
                    app.MeshArraySmoothingFrequencyLabel.Visible = 'on';
                    app.MeshArraySmoothingFrequencyLabel.Text = 'Filter Mask';
                    app.MeshGraphSmoothingFrequencyLabel.Visible = 'on';
                    app.MeshGraphSmoothingFrequencyLabel.Text = 'Mesh Graph';
                    
                case 'Motion Blur'
                    % Show motion blur controls
                    app.LengthLabel.Visible = 'on';
                    app.LengthInput.Visible = 'on';
                    app.AngleLabel.Visible = 'on';
                    app.AngleInput.Visible = 'on';
                    app.NoisevarLabel.Visible = 'on';
                    app.NoisevarInput.Visible = 'on';
                    
                    % Show PSF + 2 images (blurred + restored)
                    app.ImageMotionBlur.Visible = 'on';
                    app.ImageRestoredMotion.Visible = 'on';
                    
                    % Label untuk motion blur images
                    app.ImageWithNoiseLabel.Visible = 'on';
                    app.ImageWithNoiseLabel.Text = 'Blurred Image';
                    app.RestoredImageLabel.Visible = 'on';
                    app.RestoredImageLabel.Text = 'Restored (Wiener)';
            end
        end

        % Value changed function: SmoothingTypeDropdown
        function SmoothingTypeDropdownValueChanged(app, event)
            value = app.SmoothingTypeDropdown.Value;
            app.NamaFile.Text = value;
            if strcmp(value, 'Gaussian')
                app.SigmaLabel.Visible = 'on';
                app.SigmaInput.Visible = 'on';
            else
                app.SigmaLabel.Visible = 'off';
                app.SigmaInput.Visible = 'off';
            end
        end

        % Value changed function: SmoothingDomainDropdown
        function SmoothingDomainDropdownValueChanged(app, event)
            value = app.SmoothingDomainDropdown.Value;
    
            % Reset semua controls dulu
            app.DropdownSmoothingTypeFrequency.Visible = 'off';
            app.nMaskLabel.Visible = 'off';
            app.nMaskInput.Visible = 'off';
            app.SigmaLabel.Visible = 'off';
            app.SigmaInput.Visible = 'off';
            app.CutoffLabel.Visible = 'off';
            app.CutOffInput.Visible = 'off';
            app.OrderLabel.Visible = 'off';
            app.OrderInput.Visible = 'off';
            
            % Reset outputs
            app.GambarOutputSmoothingSpatial.Visible = 'off';
            app.GambarOutputSmoothingSpatialLabel.Visible = 'off';
            app.OutputImageSmoothingSpacial.Visible = 'off';
            app.MeshArraySmoothingSpacial.Visible = 'off';
            app.MeshGraphSmoothingSpacial.Visible = 'off';
            app.GambarOutputSmoothingFrequencyLabel.Visible = 'off';
            app.MeshArraySmoothingFrequencyLabel.Visible = 'off';
            app.MeshGraphSmoothingFrequencyLabel.Visible = 'off';
            
            % Show type dropdown dan label (untuk kedua domain)
            app.DropdownSmoothingTypeFrequency.Visible = 'on';
            app.SmoothingTypeLabel.Visible = 'on';
            
            if strcmp(value, "Spasial")
                % Set items ke Spatial types
                app.DropdownSmoothingTypeFrequency.Items = {'Mean', 'Gaussian'};
                % Reset value jika tidak valid
                if ~ismember(app.DropdownSmoothingTypeFrequency.Value, {'Mean', 'Gaussian'})
                    app.DropdownSmoothingTypeFrequency.Value = 'Mean';
                end
                
                % Show spatial controls
                app.nMaskLabel.Visible = 'on';
                app.nMaskInput.Visible = 'on';
                
                % PERBAIKAN: Show sigma if current value is Gaussian
                smoothType = app.DropdownSmoothingTypeFrequency.Value;
                if strcmp(smoothType, 'Gaussian')
                    app.SigmaLabel.Visible = 'on';
                    app.SigmaInput.Visible = 'on';
                end
                
                % Show single output
                app.GambarOutputSmoothingSpatial.Visible = 'on';
                app.GambarOutputSmoothingSpatialLabel.Visible = 'on';
                
            else  % Frekuensi
                % Set items ke Frequency types
                app.DropdownSmoothingTypeFrequency.Items = {'ILPF', 'GLPF', 'BLPF'};
                % Reset value jika tidak valid
                if ~ismember(app.DropdownSmoothingTypeFrequency.Value, {'ILPF', 'GLPF', 'BLPF'})
                    app.DropdownSmoothingTypeFrequency.Value = 'ILPF';
                end
                
                % Show frequency controls
                app.CutoffLabel.Visible = 'on';
                app.CutOffInput.Visible = 'on';
                
                % Show order only for BLPF
                filterType = app.DropdownSmoothingTypeFrequency.Value;
                if strcmp(filterType, 'BLPF')
                    app.OrderLabel.Visible = 'on';
                    app.OrderInput.Visible = 'on';
                end
                
                % Show 3 outputs
                app.OutputImageSmoothingSpacial.Visible = 'on';
                app.MeshArraySmoothingSpacial.Visible = 'on';
                app.MeshGraphSmoothingSpacial.Visible = 'on';
                app.GambarOutputSmoothingFrequencyLabel.Visible = 'on';
                app.MeshArraySmoothingFrequencyLabel.Visible = 'on';
                app.MeshGraphSmoothingFrequencyLabel.Visible = 'on';
            end
        end

        % Value changed function: DropdownSmoothingTypeFrequency
        function DropdownSmoothingTypeFrequencyValueChanged(app, event)
            value = app.DropdownSmoothingTypeFrequency.Value;
            app.OrderInput.Visible = 'off';
            app.OrderLabel.Visible = 'off';
            app.SigmaInput.Visible = 'off';
            app.SigmaLabel.Visible = 'off';
            if (value == "Gaussian")
                app.SigmaInput.Visible = 'on';
                app.SigmaLabel.Visible = 'on';
            end
            if (value == "BLPF") 
                app.OrderInput.Visible = 'on';
                app.OrderLabel.Visible = 'on';
            end
        end

        % Value changed function: 
        % DropdownSmoothingTypeEdgeDetectionFrequency
        function DropdownSmoothingTypeEdgeDetectionFrequencyValueChanged(app, event)
            value = app.DropdownSmoothingTypeEdgeDetectionFrequency.Value;
            app.OrderInput.Visible = "off";
            app.OrderLabel.Visible = "off";
            if (value == "BHPF")
                app.OrderInput.Visible = "on";
                app.OrderLabel.Visible = "on";
            end
        end

        % Value changed function: DropdownPeriodicNoiseType
        function DropdownPeriodicNoiseTypeValueChanged(app, event)
           value = app.DropdownPeriodicNoiseType.Value;
    
            app.D0DistanceLabel.Visible = 'off';
            app.D0DistanceInput.Visible = 'off';
            app.WidthLabel.Visible = 'off';
            app.WidthInput.Visible = 'off';
            app.D0LowCutoffLabel.Visible = 'off';
            app.D0LowCutoffInput.Visible = 'off';
            app.D1HighCutoffLabel.Visible = 'off';
            app.D1HighCutoffInput.Visible = 'off';
            
            if strcmp(value, 'Band Reject')
                app.D0DistanceLabel.Visible = 'on';
                app.D0DistanceInput.Visible = 'on';
                app.WidthLabel.Visible = 'on';
                app.WidthInput.Visible = 'on';
            elseif strcmp(value, 'Band Pass')
                app.D0LowCutoffLabel.Visible = 'on';
                app.D0LowCutoffInput.Visible = 'on';
                app.D1HighCutoffLabel.Visible = 'on';
                app.D1HighCutoffInput.Visible = 'on';
            else
                app.D0LowCutoffLabel.Visible = 'on';
                app.D0LowCutoffInput.Visible = 'on';
            end
        end

        % Value changed function: DropdownNoiseType
        function DropdownNoiseTypeValueChanged(app, event)
            value = app.DropdownNoiseType.Value;
    
            app.DensityLabel.Visible = 'off';
            app.DensityInput.Visible = 'off';
            app.MeanLabel.Visible = 'off';
            app.MeanInput.Visible = 'off';
            app.VarianceLabel.Visible = 'off';
            app.VarianceInput.Visible = 'off';
            
            if strcmp(value, 'Salt and Pepper')
                app.DensityLabel.Visible = 'on';
                app.DensityInput.Visible = 'on';
            else
                app.MeanLabel.Visible = 'on';
                app.MeanInput.Visible = 'on';
                app.VarianceLabel.Visible = 'on';
                app.VarianceInput.Visible = 'on';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PilihGambarButton = uibutton(app.UIFigure, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.InputImage = uiimage(app.UIFigure);
            app.FourierInput = uiimage(app.UIFigure);
            app.OutputScratch = uiimage(app.UIFigure);
            app.GambarInputLabel = uilabel(app.UIFigure);
            app.GambarInputLabel.Text = 'Gambar Input';
            app.FourierDisplayInputLabel = uilabel(app.UIFigure);
            app.FourierDisplayInputLabel.Text = 'Fourier Display Input';
            app.OutputLibrary = uiimage(app.UIFigure);
            app.GambarOutputLabel = uilabel(app.UIFigure);
            app.GambarOutputLabel.Text = 'Gambar Output';
            app.OutputLibraryLabel = uilabel(app.UIFigure);
            app.OutputLibraryLabel.Text = 'Output Library';
            app.NamaFile = uilabel(app.UIFigure);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Konvolusi', 'Image Smoothing', 'Edge Detection', 'Image Brightening', 'Noise Filtering', 'Periodic Noise', 'Motion Blur'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Konvolusi';
            app.NamaFile_2 = uilabel(app.UIFigure);
            app.NamaFile_2.Text = 'Input Mask';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            app.EditFieldMask = uieditfield(app.UIFigure, 'text');
            app.SmoothingDomainDropdown = uidropdown(app.UIFigure);
            app.SmoothingDomainDropdown.Items = {'Spasial', 'Frekuensi'};
            app.SmoothingDomainDropdown.ValueChangedFcn = createCallbackFcn(app, @SmoothingDomainDropdownValueChanged, true);
            app.SmoothingDomainDropdown.Visible = 'off';
            app.SmoothingDomainDropdown.Value = 'Spasial';
            app.SmoothingDomainLabel = uilabel(app.UIFigure);
            app.SmoothingDomainLabel.Visible = 'off';
            app.SmoothingDomainLabel.Text = 'Smoothing Domain';
            app.SmoothingTypeLabel = uilabel(app.UIFigure);
            app.SmoothingTypeLabel.Visible = 'off';
            app.SmoothingTypeLabel.Text = 'Smoothing Type';
            app.SmoothingTypeDropdown = uidropdown(app.UIFigure);
            app.SmoothingTypeDropdown.Items = {'Mean', 'Gaussian'};
            app.SmoothingTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @SmoothingTypeDropdownValueChanged, true);
            app.SmoothingTypeDropdown.Visible = 'off';
            app.SmoothingTypeDropdown.Value = 'Mean';
            app.nMaskLabel = uilabel(app.UIFigure);
            app.nMaskLabel.Visible = 'off';
            app.nMaskLabel.Text = 'n Mask';
            app.nMaskInput = uieditfield(app.UIFigure, 'numeric');
            app.nMaskInput.Visible = 'off';
            app.SigmaLabel = uilabel(app.UIFigure);
            app.SigmaLabel.Visible = 'off';
            app.SigmaLabel.Text = 'Sigma';
            app.SigmaInput = uieditfield(app.UIFigure, 'numeric');
            app.SigmaInput.Visible = 'off';
            app.GambarOutputSmoothingSpatial = uiimage(app.UIFigure);
            app.GambarOutputSmoothingSpatial.Visible = 'off';
            app.GambarOutputSmoothingSpatialLabel = uilabel(app.UIFigure);
            app.GambarOutputSmoothingSpatialLabel.Visible = 'off';
            app.GambarOutputSmoothingSpatialLabel.Text = 'Gambar Output';
            app.DropdownSmoothingTypeFrequency = uidropdown(app.UIFigure);
            app.DropdownSmoothingTypeFrequency.Items = {'ILPF', 'GLPF', 'BLPF'};
            app.DropdownSmoothingTypeFrequency.ValueChangedFcn = createCallbackFcn(app, @DropdownSmoothingTypeFrequencyValueChanged, true);
            app.DropdownSmoothingTypeFrequency.Visible = 'off';
            app.DropdownSmoothingTypeFrequency.Value = 'ILPF';
            app.CutoffLabel = uilabel(app.UIFigure);
            app.CutoffLabel.Visible = 'off';
            app.CutoffLabel.Text = 'Cutoff';
            app.CutOffInput = uieditfield(app.UIFigure, 'numeric');
            app.CutOffInput.Visible = 'off';
            app.OrderLabel = uilabel(app.UIFigure);
            app.OrderLabel.Visible = 'off';
            app.OrderLabel.Text = 'Order';
            app.OrderInput = uieditfield(app.UIFigure, 'numeric');
            app.OrderInput.Visible = 'off';
            app.OutputImageSmoothingSpacial = uiimage(app.UIFigure);
            app.OutputImageSmoothingSpacial.Visible = 'off';
            app.MeshArraySmoothingSpacial = uiimage(app.UIFigure);
            app.MeshArraySmoothingSpacial.Visible = 'off';
            app.MeshGraphSmoothingSpacial = uiimage(app.UIFigure);
            app.MeshGraphSmoothingSpacial.Visible = 'off';
            app.GambarOutputSmoothingFrequencyLabel = uilabel(app.UIFigure);
            app.GambarOutputSmoothingFrequencyLabel.Visible = 'off';
            app.GambarOutputSmoothingFrequencyLabel.Text = 'Gambar Output';
            app.MeshArraySmoothingFrequencyLabel = uilabel(app.UIFigure);
            app.MeshArraySmoothingFrequencyLabel.Visible = 'off';
            app.MeshArraySmoothingFrequencyLabel.Text = 'Mesh Arrays';
            app.MeshGraphSmoothingFrequencyLabel = uilabel(app.UIFigure);
            app.MeshGraphSmoothingFrequencyLabel.Visible = 'off';
            app.MeshGraphSmoothingFrequencyLabel.Text = 'Mesh Graph';
            app.DropDownEdgeDetectionDomain = uidropdown(app.UIFigure);
            app.DropDownEdgeDetectionDomain.Items = {'Frekuensi'};
            app.DropDownEdgeDetectionDomain.Visible = 'off';
            app.DropDownEdgeDetectionDomain.Value = 'Frekuensi';
            app.DropdownSmoothingTypeEdgeDetectionFrequency = uidropdown(app.UIFigure);
            app.DropdownSmoothingTypeEdgeDetectionFrequency.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.DropdownSmoothingTypeEdgeDetectionFrequency.ValueChangedFcn = createCallbackFcn(app, @DropdownSmoothingTypeEdgeDetectionFrequencyValueChanged, true);
            app.DropdownSmoothingTypeEdgeDetectionFrequency.Visible = 'off';
            app.DropdownSmoothingTypeEdgeDetectionFrequency.Value = 'IHPF';
            app.CutoffFrequencyLabel = uilabel(app.UIFigure);
            app.CutoffFrequencyLabel.Visible = 'off';
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.CutOffFrequencyInput = uieditfield(app.UIFigure, 'numeric');
            app.CutOffFrequencyInput.Visible = 'off';
            app.CutoffImageBrighteningLabel = uilabel(app.UIFigure);
            app.CutoffImageBrighteningLabel.Visible = 'off';
            app.CutoffImageBrighteningLabel.Text = 'Cutoff Frequency';
            app.CutOffImageBrighteningInput = uieditfield(app.UIFigure, 'numeric');
            app.CutOffImageBrighteningInput.Visible = 'off';
            app.GammaLLabel = uilabel(app.UIFigure);
            app.GammaLLabel.Visible = 'off';
            app.GammaLLabel.Text = 'Gamma L';
            app.GammaLInput = uieditfield(app.UIFigure, 'numeric');
            app.GammaLInput.Visible = 'off';
            app.GammaHLabel = uilabel(app.UIFigure);
            app.GammaHLabel.Visible = 'off';
            app.GammaHLabel.Text = 'Gamma H';
            app.GammaHInput = uieditfield(app.UIFigure, 'numeric');
            app.GammaHInput.Visible = 'off';
            app.NoiseTypeLabel = uilabel(app.UIFigure);
            app.NoiseTypeLabel.Visible = 'off';
            app.NoiseTypeLabel.Text = 'Noise Type';
            app.DropdownNoiseType = uidropdown(app.UIFigure);
            app.DropdownNoiseType.Items = {'Salt and Pepper', 'Gaussian'};
            app.DropdownNoiseType.ValueChangedFcn = createCallbackFcn(app, @DropdownNoiseTypeValueChanged, true);
            app.DropdownNoiseType.Visible = 'off';
            app.DropdownNoiseType.Value = 'Salt and Pepper';
            app.FilterTypeLabel = uilabel(app.UIFigure);
            app.FilterTypeLabel.Visible = 'off';
            app.FilterTypeLabel.Text = 'Filter Type';
            app.DropdownNoiseType_2 = uidropdown(app.UIFigure);
            app.DropdownNoiseType_2.Items = {'Min', 'Max', 'Median', 'Arithmetic', 'Geomethric', 'Contraharmonic', 'Midpoint', 'Alpha Trimmed', ''};
            app.DropdownNoiseType_2.Visible = 'off';
            app.DropdownNoiseType_2.Value = 'Min';
            app.nWindowLabel = uilabel(app.UIFigure);
            app.nWindowLabel.Visible = 'off';
            app.nWindowLabel.Text = 'n Window';
            app.nWindowInput = uieditfield(app.UIFigure, 'numeric');
            app.nWindowInput.Visible = 'off';
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Visible = 'off';
            app.MeanLabel.Text = 'Mean';
            app.MeanInput = uieditfield(app.UIFigure, 'numeric');
            app.MeanInput.Visible = 'off';
            app.DensityLabel = uilabel(app.UIFigure);
            app.DensityLabel.Visible = 'off';
            app.DensityLabel.Text = 'Density';
            app.DensityInput = uieditfield(app.UIFigure, 'numeric');
            app.DensityInput.Visible = 'off';
            app.VarianceLabel = uilabel(app.UIFigure);
            app.VarianceLabel.Visible = 'off';
            app.VarianceLabel.Text = 'Variance';
            app.VarianceInput = uieditfield(app.UIFigure, 'numeric');
            app.VarianceInput.Visible = 'off';
            app.OutputRestoredNoise = uiimage(app.UIFigure);
            app.OutputRestoredNoise.Visible = 'off';
            app.NoiseImage = uiimage(app.UIFigure);
            app.NoiseImage.Visible = 'off';
            app.RestoredImageLabel = uilabel(app.UIFigure);
            app.RestoredImageLabel.Visible = 'off';
            app.RestoredImageLabel.Text = 'Restored Image';
            app.ImageWithNoiseLabel = uilabel(app.UIFigure);
            app.ImageWithNoiseLabel.Visible = 'off';
            app.ImageWithNoiseLabel.Text = 'Image With Noise';
            app.FilterTypePeriodicNoiseLabel = uilabel(app.UIFigure);
            app.FilterTypePeriodicNoiseLabel.Visible = 'off';
            app.FilterTypePeriodicNoiseLabel.Text = 'Filter Type';
            app.DropdownPeriodicNoiseType = uidropdown(app.UIFigure);
            app.DropdownPeriodicNoiseType.Items = {'Band Reject', 'Band Pass', 'Notch'};
            app.DropdownPeriodicNoiseType.ValueChangedFcn = createCallbackFcn(app, @DropdownPeriodicNoiseTypeValueChanged, true);
            app.DropdownPeriodicNoiseType.Visible = 'off';
            app.DropdownPeriodicNoiseType.Value = 'Band Reject';
            app.D0DistanceLabel = uilabel(app.UIFigure);
            app.D0DistanceLabel.Visible = 'off';
            app.D0DistanceLabel.Text = 'D0 (Distance)';
            app.D0DistanceInput = uieditfield(app.UIFigure, 'numeric');
            app.D0DistanceInput.Visible = 'off';
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Visible = 'off';
            app.WidthLabel.Text = 'Width';
            app.WidthInput = uieditfield(app.UIFigure, 'numeric');
            app.WidthInput.Visible = 'off';
            app.D0LowCutoffLabel = uilabel(app.UIFigure);
            app.D0LowCutoffLabel.Visible = 'off';
            app.D0LowCutoffLabel.Text = 'D0 (Low Cutoff)';
            app.D0LowCutoffInput = uieditfield(app.UIFigure, 'numeric');
            app.D0LowCutoffInput.Visible = 'off';
            app.D1HighCutoffLabel = uilabel(app.UIFigure);
            app.D1HighCutoffLabel.Visible = 'off';
            app.D1HighCutoffLabel.Text = 'D1 (High Cutoff)';
            app.D1HighCutoffInput = uieditfield(app.UIFigure, 'numeric');
            app.D1HighCutoffInput.Visible = 'off';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Visible = 'off';
            app.LengthLabel.Text = 'Length';
            app.LengthInput = uieditfield(app.UIFigure, 'numeric');
            app.LengthInput.Visible = 'off';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Visible = 'off';
            app.AngleLabel.Text = 'Angle';
            app.AngleInput = uieditfield(app.UIFigure, 'numeric');
            app.AngleInput.Visible = 'off';
            app.NoisevarLabel = uilabel(app.UIFigure);
            app.NoisevarLabel.Visible = 'off';
            app.NoisevarLabel.Text = 'Noise var';
            app.NoisevarInput = uieditfield(app.UIFigure, 'numeric');
            app.NoisevarInput.Visible = 'off';
            app.ImageRestoredMotion = uiimage(app.UIFigure);
            app.ImageRestoredMotion.Visible = 'off';
            app.ImageMotionBlur = uiimage(app.UIFigure);
            app.ImageMotionBlur.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tugas2PCDmain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end